classdef classifier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SVMClassifierPatchAnnotatorUIFigure  matlab.ui.Figure
        TotalTrainingPatchesLabel    matlab.ui.control.Label
        ViewAnnotationCheckBox       matlab.ui.control.CheckBox
        ViewSampleIDCheckBox         matlab.ui.control.CheckBox
        ViewAllCheckBox              matlab.ui.control.CheckBox
        MarkerSizeSpinner            matlab.ui.control.Spinner
        MarkerSizeLabel              matlab.ui.control.Label
        SubsetClassesListBox         matlab.ui.control.ListBox
        SubsetClassesListBoxLabel    matlab.ui.control.Label
        SliceSpinner                 matlab.ui.control.Spinner
        SliceLabel                   matlab.ui.control.Label
        BlueDropDown                 matlab.ui.control.DropDown
        BlueDropDownLabel            matlab.ui.control.Label
        GreenDropDown                matlab.ui.control.DropDown
        GreenDropDownLabel           matlab.ui.control.Label
        RedDropDown                  matlab.ui.control.DropDown
        RedDropDownLabel             matlab.ui.control.Label
        RecordingCheckBox            matlab.ui.control.CheckBox
        SaveSetButton                matlab.ui.control.Button
        ShortcutKeysButton           matlab.ui.control.Button
        GoToNextButton               matlab.ui.control.Button
        BlueCheckBox                 matlab.ui.control.CheckBox
        BlueIntensitySlider          matlab.ui.control.Slider
        GreenCheckBox                matlab.ui.control.CheckBox
        GreenIntensitySlider         matlab.ui.control.Slider
        DefineClassifierTrainingSetLabel  matlab.ui.control.Label
        LoadSet                      matlab.ui.control.Button
        RedCheckBox                  matlab.ui.control.CheckBox
        RenameSet                    matlab.ui.control.EditField
        CreateNewSet                 matlab.ui.control.Button
        RedIntensitySlider           matlab.ui.control.Slider
        TabGroup                     matlab.ui.container.TabGroup
        ImageViewerTab               matlab.ui.container.Tab
        IDLabel                      matlab.ui.control.Label
        CTLabel                      matlab.ui.control.Label
        MarkerLabel                  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        ConfusionMatrixTab           matlab.ui.container.Tab
        GenerateModelPanel           matlab.ui.container.Panel
        TrainthemodelonallorasubsetofpatchfeaturesLabel  matlab.ui.control.Label
        AllotherclassesaretreatedasoutliersandareignoredLabel  matlab.ui.control.Label
        UseClassesListBox            matlab.ui.control.ListBox
        UseClassesListBoxLabel       matlab.ui.control.Label
        UseFeaturesListBox           matlab.ui.control.ListBox
        UseFeaturesListBoxLabel      matlab.ui.control.Label
        ClassificationAccuracyLabel  matlab.ui.control.Label
        TrainButton                  matlab.ui.control.Button
        MethodDropDown               matlab.ui.control.DropDown
        MethodDropDownLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        input_directory = "";
        home_path = "";
        img_dim = [101, 101, 3];
        disp_img = zeros(101,101,3,'uint8');
        n_slices = 0;
        slice = 1;
        n_channels = 1;
        sel_channels = [false, false, false];
        c_idx = [1, 2, 3];
        sel_intensity = {[0, 1], [0, 1], [0, 1]};
        u_classes = [];
        sel_classes = 0;
        featureNames = [];
        isrecording = false;
        isloaded = false;  
        classif = struct(); % Classifier structure       
    end
    
    methods (Access = private)
        
        function create_new_classif(app, input_directory, append)
            if nargin <3; append = false; end
            
            % Create new classifier structure
            files = dir(fullfile(input_directory,'*.csv'));
            sample_ids = arrayfun(@(s) strsplit(s.name, '_'), files, 'UniformOutput', false);
            sample_ids = unique(cellfun(@(s) s{1}, sample_ids, 'UniformOutput', false));
            
            if length(sample_ids) == 1
                classifier_id = sample_ids{1};
            else
                [~, classifier_id] = fileparts(input_directory);
            end
            
            % Get cell feature information
            info = cell(1,length(sample_ids));
            features = cell(1,length(sample_ids));
            img = cell(1, length(sample_ids));
            n_patches = zeros(1,length(sample_ids));
            set_idx = cell(1, length(sample_ids));
            for i = 1:length(sample_ids)
                info{i} = readmatrix(char(fullfile(input_directory, strcat(sample_ids(i), '_patch_info.csv'))));
                features{i} = readmatrix(char(fullfile(input_directory, strcat(sample_ids(i), '_patch_features.csv'))));
                if i == 1
                    fNames = readtable(char(fullfile(input_directory, strcat(sample_ids(i), '_patch_features.csv'))),'NumHeaderLines',0);
                    fNames = fNames.Properties.VariableNames;
                end
                img{i} = loadtiff(char(fullfile(input_directory, strcat(sample_ids(i), '_patches.tif'))));
                n_patches(i) = size(features{i},1);
                set_idx{i} = repmat(i, 1, n_patches(i));
                assert(size(features{i},1) == size(info{i},1),...
                    sprintf("Feature and info tables are incosistent for sample %s", sample_ids{i}))
            end
            assert(all(cellfun(@(s) size(s,1) == size(img{1}, 1), img)), "All patches must of be of the same size")

            if append
                app.classif.info = cat(1, app.classif.info, info{:});
                app.classif.features = cat(1, app.classif.features, features{:});
                app.classif.featureNames = fNames;
                app.classif.n_patches = cat(2, app.classif.n_patches, n_patches);
                app.classif.patches = cat(4, app.classif.patches, img{:});
                s1 = length(app.classif.sample_ids);
                set_idx = cellfun(@(s) s + s1, set_idx, 'UniformOutput', false); 
                app.classif.sample_ids = cat(1, app.classif.sample_ids, sample_ids);
                s = [cat(2,set_idx{:}); 1:size(cat(4,img{:}),4)];
                app.classif.set_idx = cat(2, app.classif.set_idx, s);
                app.classif.classes = cat(2, app.classif.classes, zeros(1,sum(n_patches(:))));
            else
                app.classif.id = strcat(classifier_id, '_training_classes.mat');
                app.classif.info = cat(1,info{:});
                app.classif.features = cat(1,features{:});
                app.classif.featureNames = fNames;
                app.classif.n_patches = n_patches;
                app.classif.patches = cat(4,img{:});
                app.classif.sample_ids = sample_ids;
                app.classif.set_idx = [cat(2,set_idx{:}); 1:size(app.classif.patches,4)];
                app.classif.classes = zeros(1,sum(n_patches(:)));
            end
            
            % Shuffle the images
            app.classif.set_idx = app.classif.set_idx(:,randperm(size(app.classif.set_idx,2)));
        end
        
        function results = load_data(app, isnew)
            % Load data from previous structure
            app.SVMClassifierPatchAnnotatorUIFigure.Visible = 'off';
            commandwindow();
            if isnew
                results = uigetdir(app.home_path, "Select input directory");
            else
                [file, path] = uigetfile('*.mat', app.home_path, "Select input structure");
                results = fullfile(path, file);
                app.isloaded = true;
            end
            app.SVMClassifierPatchAnnotatorUIFigure.Visible = 'on';
        end
        
        function advance_slice(app, direction)            

            idx = ismember(app.classif.classes, app.sel_classes);

            if isequal(direction,'r')
                % Advance forward
                app.slice = app.slice+1;
                if app.slice > app.n_slices
                    app.slice = 1;
                end
                while ~idx(app.slice)
                    app.slice = app.slice+1;
                    if app.slice > app.n_slices
                        app.slice = 1;            
                    end
                end
            elseif isequal(direction, 'l')
                % Advance back
                app.slice = app.slice-1;
                if app.slice < 1
                    app.slice = app.n_slices;
                end
                while ~idx(app.slice)
                    app.slice = app.slice-1;
                    if app.slice < 1
                        app.slice = app.n_slices;
                    end
                end
            else
                % Advance forward unless current image is within class
                if ~idx(app.slice)
                    app.slice = app.slice+1;
                    if app.slice > app.n_slices
                        app.slice = 1;
                    end
                    while ~idx(app.slice)
                        app.slice = app.slice+1;
                        if app.slice > app.n_slices
                            app.slice = 1;            
                        end
                    end
                end
            end
            display_slice(app);
            app.SliceSpinner.Value = app.slice;
        end

        
        function display_slice(app)
            z = app.classif.set_idx(2,app.slice);

            if app.RedCheckBox.Value                
                app.disp_img(:,:,1) = app.classif.patches(:,:,app.c_idx(1),z);
                app.disp_img(:,:,1) = imadjust(app.disp_img(:,:,1), app.sel_intensity{1});
            else
                app.disp_img(:,:,1) = 0;
            end
            if app.GreenCheckBox.Value
                app.disp_img(:,:,2) = app.classif.patches(:,:,app.c_idx(2),z);
                app.disp_img(:,:,2) = imadjust(app.disp_img(:,:,2), app.sel_intensity{2});
            else
                app.disp_img(:,:,2) = 0;
            end
            if app.BlueCheckBox.Value
                app.disp_img(:,:,3) = app.classif.patches(:,:,app.c_idx(3),z);
                app.disp_img(:,:,3) = imadjust(app.disp_img(:,:,3), app.sel_intensity{3});
            else
                app.disp_img(:,:,3) = 0;
            end

            app.Image.ImageSource = app.disp_img;
            update_labels(app);
        end
        
        function update_labels(app)
            % Update image figure labels
            app.CTLabel.Text = "CT" + num2str(app.classif.classes(app.slice));
            app.IDLabel.Text = string(app.classif.sample_ids{app.classif.set_idx(1,app.slice)});

            if ~app.ViewSampleIDCheckBox.Value
                app.IDLabel.Visible = 'off';
            end
            if ~app.ViewAnnotationCheckBox.Value
                %app.IDLabel.Visible = 'off';
            end
        end
        
        function check_CTs(app)
            all_classes = unique(app.classif.classes);
            % Add new class to list
            if ~all(ismember(all_classes, app.u_classes))  
                idx = all_classes(~ismember(all_classes, app.u_classes));
                for i = 1:length(idx)
                    new_node = char("CT" + string(idx(i)));
    
                    app.SubsetClassesListBox.Items{end+1} = new_node;
                    app.SubsetClassesListBox.ItemsData = [app.SubsetClassesListBox.ItemsData, idx(i)];
                    
                    if ~isequal(new_node, "CT0")
                        app.UseClassesListBox.Items{end+1} = new_node;
                        app.UseClassesListBox.ItemsData{end+1} = new_node;   
                        app.UseClassesListBox.Value{end+1} = new_node;
                    end
                end
                app.u_classes = all_classes;
            end

            % Check for selected classes
            if app.ViewAllCheckBox.Value
                app.sel_classes = app.SubsetClassesListBox.ItemsData;
            else
                idx = ismember(app.SubsetClassesListBox.ItemsData, app.sel_classes);
                app.SubsetClassesListBox.Value = app.SubsetClassesListBox.ItemsData(idx);
            end
        end
        
        function init_classif(app)
            % Initialize
            app.n_channels = size(app.classif.patches,3);
            app.n_slices = length(app.classif.classes);
            
            app.SliceSpinner.Value = app.slice;
            app.img_dim = size(app.classif.patches(:,:,:,1));
            app.disp_img = zeros(size(app.classif.patches(:,:,1)),'uint8');
            app.SliceSpinner.Value = app.slice;

            app.RedDropDown.Items = "Ch" + string(1:app.n_channels);
            app.GreenDropDown.Items = "Ch" + string(1:app.n_channels);
            app.BlueDropDown.Items = "Ch" + string(1:app.n_channels);

            % Check channel selection
            if app.n_channels >= 1
                app.RedCheckBox.Value = true;
                app.RedDropDown.Value = app.RedDropDown.Items(1);
                app.disp_img(:,:,1) = app.classif.patches(:,:,1,app.slice);
            end
            if app.n_channels >= 2
                app.GreenCheckBox.Value = true;
                app.GreenDropDown.Value = app.GreenDropDown.Items(2);
                app.disp_img(:,:,2) = app.classif.patches(:,:,2,app.slice);
            end
            if app.n_channels >= 3
                app.BlueCheckBox.Value = true;
                app.BlueDropDown.Value = app.BlueDropDown.Items(3);
                app.disp_img(:,:,3) = app.classif.patches(:,:,3,app.slice);
            end

            % Check classes and display
            check_CTs(app);
            display_slice(app);

            % Show features
            app.featureNames = app.classif.featureNames;
            app.featureNames = string(unique(cellfun(@(s) s(1:strfind(s,'_')-1),...
                app.featureNames, 'UniformOutput', false),'stable'));
            app.UseFeaturesListBox.Items = app.featureNames;
            app.UseFeaturesListBox.Value = app.featureNames;
            
        end
        
        function results = run_training(app)

            a = 1;
            b = 1;

            app.classif = train_classifier(app.classif, use_classes, use_features);

            c = 1;


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.home_path = fileparts(which('NM_config'));
            app.Image.ImageSource = app.disp_img;
            %file = '/Users/olehkrupa/MATLAB/numorph/patches/patches_training_classes.mat';
            %LoadSetButtonPushed(app, file)

        end

        % Window key press function: SVMClassifierPatchAnnotatorUIFigure
        function SVMClassifierPatchAnnotatorUIFigureWindowKeyPress(app, event)
            key = event.Key;
            
            if ~app.isloaded
                return
            end
            class_keys = {'1','2','3','4','5','6','7','8','9'};

            if ismember(key,class_keys) && ~app.isrecording 
                return
            end

            switch key
                case 'space'
                    app.isrecording = ~app.isrecording;
                    app.RecordingCheckBox.Value = ~app.RecordingCheckBox.Value;
                case 'rightarrow'
                    advance_slice(app, 'r');
                case 'leftarrow'
                    advance_slice(app, 'l');
                case '1'
                    app.classif.classes(app.slice) = 1;
                case '2'
                    app.classif.classes(app.slice) = 2;
                case '3'
                    app.classif.classes(app.slice) = 3;
                case '4'
                    app.classif.classes(app.slice) = 4;
                case '5'
                    app.classif.classes(app.slice) = 5;
                case '6'
                    app.classif.classes(app.slice) = 6;
                case '7'
                    app.classif.classes(app.slice) = 7;
                case '8'
                    app.classif.classes(app.slice) = 8;
                case '9'
                    app.classif.classes(app.slice) = 9;
            end

            if ismember(key,class_keys)
                % Add to count
                count = sum(app.classif.classes ~= 0);
                app.TotalTrainingPatchesLabel.Text = sprintf("Total Training Patches: %d", count);


                % Advance slice and check for update classes
                advance_slice(app, 'r');
                check_CTs(app);
            end

            display_slice(app);

        end

        % Button pushed function: CreateNewSet
        function CreateNewSetButtonPushed(app, event)
            % Check whether data is already loaded
            if app.isloaded
                title = "Create new dataset";
                msg = "A classifier dataset is already loaded. "+...
                    "Append images or create a new classifier?";
                selection = uiconfirm(app.SVMClassifierPatchAnnotatorUIFigure,msg,title,...
                    'Options', {'Append', 'Create New', 'Cancel'});
                
                switch selection
                    case 'Append'
                        app.input_directory = load_data(app, true);
                        create_new_classif(app, app.input_directory, true);
                        init_classif(app);            

                    case 'Create New'
                        app.input_directory = load_data(app, true);
                        create_new_classif(app, app.input_directory, false);
                        init_classif(app); 
                    otherwise
                        return
                end
            else
                app.input_directory = load_data(app, true);
                create_new_classif(app, app.input_directory, false);
                init_classif(app);            
            end
            app.RenameSet.Value = app.classif.id;
            app.isloaded = true;

            app.CreateNewSet.Enable = 'off';
            drawnow;
            app.CreateNewSet.Enable = 'on';

            app.RecordingCheckBox.Value = true;
            RecordingCheckBoxValueChanged(app);

        end

        % Button pushed function: LoadSet
        function LoadSetButtonPushed(app, event)

            if ~ischar(event)
                file = load_data(app, false);
            else
                file = event;
            end
            app.classif = load(file);                       
            init_classif(app);
            app.RenameSet.Value = app.classif.id;
            app.isloaded = true;

            app.CreateNewSet.Enable = 'off';
            drawnow;
            app.CreateNewSet.Enable = 'on';

        end

        % Button pushed function: SaveSetButton
        function SaveSetButtonPushed(app, event)
            
            save_name = app.classif.id;
            classif = app.classif;
            input_path = fullfile(app.input_directory, save_name);
            app.SVMClassifierPatchAnnotatorUIFigure.Visible = 'off';
            %commandwindow;
            [file, path] = uiputfile('*mat','File Selection',save_name);
            app.SVMClassifierPatchAnnotatorUIFigure.Visible = 'on';
            save_name = fullfile(path, file);
            save(save_name, '-struct', 'classif')

        end

        % Value changed function: SliceSpinner
        function SliceSpinnerValueChanged(app, event)
            value = app.SliceSpinner.Value;
            if value < 1
                app.SliceSpinner.Value = 1;
                value = 1;
            elseif value > app.n_slices
                app.SliceSpinner.Value = app.n_slices;
                value = app.n_slices;
            end
            app.slice = value;
            display_slice(app)

        end

        % Value changed function: RedCheckBox
        function RedCheckBoxValueChanged(app, event)
            app.sel_channels(1) = app.RedCheckBox.Value;
            display_slice(app);

        end

        % Value changed function: RedDropDown
        function RedDropDownValueChanged(app, event)
            value = app.RedDropDown.Value;
            app.c_idx(1) = str2double(value(3:end));
            display_slice(app);
            
        end

        % Value changed function: RedIntensitySlider
        function RedIntensitySliderValueChanged(app, event)

            changingValue = app.RedIntensitySlider.Value/100;
            if changingValue == 0
                changingValue = 0.01;
            end
            app.sel_intensity{1}(2) = changingValue;
            display_slice(app);

        end

        % Value changed function: GreenCheckBox
        function GreenCheckBoxValueChanged(app, event)
            app.sel_channels(2) = app.RedCheckBox.Value;
            display_slice(app);

        end

        % Value changed function: GreenDropDown
        function GreenDropDownValueChanged(app, event)
            value = app.GreenDropDown.Value;
            app.c_idx(2) = str2double(value(3:end));
            display_slice(app);
            
        end

        % Value changed function: GreenIntensitySlider
        function GreenIntensitySliderValueChanged(app, event)
            
            changingValue = app.GreenIntensitySlider.Value/100;
            if changingValue == 0
                changingValue = 0.01;
            end
            app.sel_intensity{2}(2) = changingValue;
            display_slice(app);
            
        end

        % Value changed function: BlueCheckBox
        function BlueCheckBoxValueChanged(app, event)
            app.sel_channels(3) = app.RedCheckBox.Value;
            display_slice(app);

        end

        % Value changed function: BlueDropDown
        function BlueDropDownValueChanged(app, event)
            value = app.BlueDropDown.Value;
            app.c_idx(3) = str2double(value(3:end));
            display_slice(app);
            
        end

        % Value changed function: BlueIntensitySlider
        function BlueIntensitySliderValueChanged(app, event)
            
            changingValue = app.BlueIntensitySlider.Value/100;
            if changingValue == 0
                changingValue = 0.01;
            end
            app.sel_intensity{3}(2) = changingValue;
            display_slice(app);
            
        end

        % Value changed function: RecordingCheckBox
        function RecordingCheckBoxValueChanged(app, event)
            app.isrecording = app.RecordingCheckBox.Value;
            
        end

        % Button pushed function: GoToNextButton
        function GoToNextButtonPushed(app, event)
            selClasses = app.sel_classes;
            if ~ismember(0, selClasses)
                app.sel_classes = [0, selClasses];
                app.SubsetClassesListBox.Value = [0, app.SubsetClassesListBox.Value];
            end

            app.sel_classes = 0;
            advance_slice(app, 'n')
            app.sel_classes = selClasses;

        end

        % Value changed function: SubsetClassesListBox
        function SubsetClassesListBoxValueChanged(app, event)
            value = app.SubsetClassesListBox.Value;
            app.sel_classes = value;
            advance_slice(app, 'n');

        end

        % Value changed function: MarkerSizeSpinner
        function MarkerSizeSpinnerValueChanged(app, event)
            value = app.MarkerSizeSpinner.Value;
            app.MarkerLabel.FontSize = value;
            
        end

        % Value changed function: ViewAllCheckBox
        function ViewAllCheckBoxValueChanged(app, event)
            value = app.ViewAllCheckBox.Value;
            if value
                app.sel_classes = app.SubsetClassesListBox.ItemsData;
            else
                app.sel_classes = app.SubsetClassesListBox.Value;
            end
            if isempty(app.sel_classes)
                app.sel_classes = 0;
                app.SubsetClassesListBox.Value = 0;
            end
            advance_slice(app,'n');
        end

        % Button pushed function: ShortcutKeysButton
        function ShortcutKeysButtonPushed(app, event)

            %app.SVMClassifierPatchAnnotatorUIFigure.Visible = 'off';
            title = "Classifier Keyboard Shorcuts";
            msg = ["Annotate a sufficient number of patches containing cell-types of interest. "+...
                "Then generate a model using the training data. Annotate more patches to improve accuracy.",...
                "",...
                "Manual annotation key strokes:",...
                "",...
                "space:                 toggle class recording",...
                "left/right arrow:      navigate patch slice",...
                "1-9:                   assign class annotation"];
            uialert(app.SVMClassifierPatchAnnotatorUIFigure, msg, title,...
                'Icon','info');

        end

        % Value changed function: ViewSampleIDCheckBox
        function ViewSampleIDCheckBoxValueChanged(app, event)
            value = app.ViewSampleIDCheckBox.Value;
            if value
                app.IDLabel.Visible = 'on';
            else
                app.IDLabel.Visible = 'off';
            end
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)

            use_classes = cellfun(@(s) str2double(s(end)), app.UseClassesListBox.Value);
            use_features = string(app.UseFeaturesListBox.Value);
            app.classif = train_classifier(app.classif, use_classes, use_features);

            % Display accuracy
            acc = app.classif.validationAccuracy*100;
            msg = sprintf("Classification Accuracy: %.2f%%", acc);
            app.ClassificationAccuracyLabel.Text = msg;

            % Display confusion matrix
            confusionchart(app.TabGroup.Children(3), app.classif.confusionmatrix,...
                string(app.UseClassesListBox.Value));
            app.TabGroup.SelectedTab = app.TabGroup.Children(3);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SVMClassifierPatchAnnotatorUIFigure = uifigure('Visible', 'off');
            app.SVMClassifierPatchAnnotatorUIFigure.Name = 'SVM Classifier Patch Annotator';
            app.SVMClassifierPatchAnnotatorUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @SVMClassifierPatchAnnotatorUIFigureWindowKeyPress, true);
            app.GenerateModelPanel = uipanel(app.SVMClassifierPatchAnnotatorUIFigure);
            app.GenerateModelPanel.Title = 'Generate Model';
            app.MethodDropDownLabel = uilabel(app.GenerateModelPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.GenerateModelPanel);
            app.MethodDropDown.Items = {'SVM'};
            app.MethodDropDown.Value = 'SVM';
            app.TrainButton = uibutton(app.GenerateModelPanel, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.ClassificationAccuracyLabel = uilabel(app.GenerateModelPanel);
            app.ClassificationAccuracyLabel.Text = 'Classification Accuracy';
            app.UseFeaturesListBoxLabel = uilabel(app.GenerateModelPanel);
            app.UseFeaturesListBoxLabel.Text = 'Use Features';
            app.UseFeaturesListBox = uilistbox(app.GenerateModelPanel);
            app.UseFeaturesListBox.Items = {};
            app.UseFeaturesListBox.Multiselect = 'on';
            app.UseFeaturesListBox.Value = {};
            app.UseClassesListBoxLabel = uilabel(app.GenerateModelPanel);
            app.UseClassesListBoxLabel.Text = 'Use Classes';
            app.UseClassesListBox = uilistbox(app.GenerateModelPanel);
            app.UseClassesListBox.Items = {};
            app.UseClassesListBox.Multiselect = 'on';
            app.UseClassesListBox.Value = {};
            app.AllotherclassesaretreatedasoutliersandareignoredLabel = uilabel(app.GenerateModelPanel);
            app.AllotherclassesaretreatedasoutliersandareignoredLabel.Text = 'All other classes are treated as outliers and are ignored.';
            app.TrainthemodelonallorasubsetofpatchfeaturesLabel = uilabel(app.GenerateModelPanel);
            app.TrainthemodelonallorasubsetofpatchfeaturesLabel.Text = 'Train the model on all or a subset of patch features.';
            app.TabGroup = uitabgroup(app.SVMClassifierPatchAnnotatorUIFigure);
            app.ImageViewerTab = uitab(app.TabGroup);
            app.ImageViewerTab.Title = 'Image Viewer';
            app.Image = uiimage(app.ImageViewerTab);
            app.MarkerLabel = uilabel(app.ImageViewerTab);
            app.MarkerLabel.Text = '+';
            app.CTLabel = uilabel(app.ImageViewerTab);
            app.CTLabel.Text = 'CT';
            app.IDLabel = uilabel(app.ImageViewerTab);
            app.IDLabel.Text = 'ID';
            app.ConfusionMatrixTab = uitab(app.TabGroup);
            app.ConfusionMatrixTab.Title = 'Confusion Matrix';
            app.RedIntensitySlider = uislider(app.SVMClassifierPatchAnnotatorUIFigure);
            app.RedIntensitySlider.MajorTicks = [0 25 50 75 100];
            app.RedIntensitySlider.ValueChangedFcn = createCallbackFcn(app, @RedIntensitySliderValueChanged, true);
            app.RedIntensitySlider.Value = 100;
            app.CreateNewSet = uibutton(app.SVMClassifierPatchAnnotatorUIFigure, 'push');
            app.CreateNewSet.ButtonPushedFcn = createCallbackFcn(app, @CreateNewSetButtonPushed, true);
            app.CreateNewSet.Text = 'Create New';
            app.RenameSet = uieditfield(app.SVMClassifierPatchAnnotatorUIFigure, 'text');
            app.RenameSet.Editable = 'off';
            app.RedCheckBox = uicheckbox(app.SVMClassifierPatchAnnotatorUIFigure);
            app.RedCheckBox.ValueChangedFcn = createCallbackFcn(app, @RedCheckBoxValueChanged, true);
            app.RedCheckBox.Text = '';
            app.LoadSet = uibutton(app.SVMClassifierPatchAnnotatorUIFigure, 'push');
            app.LoadSet.ButtonPushedFcn = createCallbackFcn(app, @LoadSetButtonPushed, true);
            app.LoadSet.Text = 'Load';
            app.DefineClassifierTrainingSetLabel = uilabel(app.SVMClassifierPatchAnnotatorUIFigure);
            app.DefineClassifierTrainingSetLabel.Text = 'Define Classifier Training Set';
            app.GreenIntensitySlider = uislider(app.SVMClassifierPatchAnnotatorUIFigure);
            app.GreenIntensitySlider.MajorTicks = [0 25 50 75 100];
            app.GreenIntensitySlider.ValueChangedFcn = createCallbackFcn(app, @GreenIntensitySliderValueChanged, true);
            app.GreenIntensitySlider.Value = 100;
            app.GreenCheckBox = uicheckbox(app.SVMClassifierPatchAnnotatorUIFigure);
            app.GreenCheckBox.ValueChangedFcn = createCallbackFcn(app, @GreenCheckBoxValueChanged, true);
            app.GreenCheckBox.Text = '';
            app.BlueIntensitySlider = uislider(app.SVMClassifierPatchAnnotatorUIFigure);
            app.BlueIntensitySlider.MajorTicks = [0 25 50 75 100];
            app.BlueIntensitySlider.ValueChangedFcn = createCallbackFcn(app, @BlueIntensitySliderValueChanged, true);
            app.BlueIntensitySlider.Value = 100;
            app.BlueCheckBox = uicheckbox(app.SVMClassifierPatchAnnotatorUIFigure);
            app.BlueCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlueCheckBoxValueChanged, true);
            app.BlueCheckBox.Text = '';
            app.GoToNextButton = uibutton(app.SVMClassifierPatchAnnotatorUIFigure, 'push');
            app.GoToNextButton.ButtonPushedFcn = createCallbackFcn(app, @GoToNextButtonPushed, true);
            app.GoToNextButton.Text = 'Go To Next';
            app.ShortcutKeysButton = uibutton(app.SVMClassifierPatchAnnotatorUIFigure, 'push');
            app.ShortcutKeysButton.ButtonPushedFcn = createCallbackFcn(app, @ShortcutKeysButtonPushed, true);
            app.ShortcutKeysButton.Text = 'Shortcut Keys';
            app.SaveSetButton = uibutton(app.SVMClassifierPatchAnnotatorUIFigure, 'push');
            app.SaveSetButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSetButtonPushed, true);
            app.SaveSetButton.Text = 'Save';
            app.RecordingCheckBox = uicheckbox(app.SVMClassifierPatchAnnotatorUIFigure);
            app.RecordingCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecordingCheckBoxValueChanged, true);
            app.RecordingCheckBox.Text = 'Recording';
            app.RedDropDownLabel = uilabel(app.SVMClassifierPatchAnnotatorUIFigure);
            app.RedDropDownLabel.Text = 'Red';
            app.RedDropDown = uidropdown(app.SVMClassifierPatchAnnotatorUIFigure);
            app.RedDropDown.ValueChangedFcn = createCallbackFcn(app, @RedDropDownValueChanged, true);
            app.GreenDropDownLabel = uilabel(app.SVMClassifierPatchAnnotatorUIFigure);
            app.GreenDropDownLabel.Text = 'Green';
            app.GreenDropDown = uidropdown(app.SVMClassifierPatchAnnotatorUIFigure);
            app.GreenDropDown.ValueChangedFcn = createCallbackFcn(app, @GreenDropDownValueChanged, true);
            app.BlueDropDownLabel = uilabel(app.SVMClassifierPatchAnnotatorUIFigure);
            app.BlueDropDownLabel.Text = 'Blue';
            app.BlueDropDown = uidropdown(app.SVMClassifierPatchAnnotatorUIFigure);
            app.BlueDropDown.ValueChangedFcn = createCallbackFcn(app, @BlueDropDownValueChanged, true);
            app.SliceLabel = uilabel(app.SVMClassifierPatchAnnotatorUIFigure);
            app.SliceLabel.Text = 'Slice';
            app.SliceSpinner = uispinner(app.SVMClassifierPatchAnnotatorUIFigure);
            app.SliceSpinner.ValueChangedFcn = createCallbackFcn(app, @SliceSpinnerValueChanged, true);
            app.SubsetClassesListBoxLabel = uilabel(app.SVMClassifierPatchAnnotatorUIFigure);
            app.SubsetClassesListBoxLabel.Text = 'Subset Classes';
            app.SubsetClassesListBox = uilistbox(app.SVMClassifierPatchAnnotatorUIFigure);
            app.SubsetClassesListBox.Items = {};
            app.SubsetClassesListBox.Multiselect = 'on';
            app.SubsetClassesListBox.ValueChangedFcn = createCallbackFcn(app, @SubsetClassesListBoxValueChanged, true);
            app.SubsetClassesListBox.Value = {};
            app.MarkerSizeLabel = uilabel(app.SVMClassifierPatchAnnotatorUIFigure);
            app.MarkerSizeLabel.Text = {'Marker'; 'Size'};
            app.MarkerSizeSpinner = uispinner(app.SVMClassifierPatchAnnotatorUIFigure);
            app.MarkerSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @MarkerSizeSpinnerValueChanged, true);
            app.MarkerSizeSpinner.Value = 36;
            app.ViewAllCheckBox = uicheckbox(app.SVMClassifierPatchAnnotatorUIFigure);
            app.ViewAllCheckBox.ValueChangedFcn = createCallbackFcn(app, @ViewAllCheckBoxValueChanged, true);
            app.ViewAllCheckBox.Text = 'View All';
            app.ViewAllCheckBox.Value = true;
            app.ViewSampleIDCheckBox = uicheckbox(app.SVMClassifierPatchAnnotatorUIFigure);
            app.ViewSampleIDCheckBox.ValueChangedFcn = createCallbackFcn(app, @ViewSampleIDCheckBoxValueChanged, true);
            app.ViewSampleIDCheckBox.Text = 'View Sample ID';
            app.ViewSampleIDCheckBox.Value = true;
            app.ViewAnnotationCheckBox = uicheckbox(app.SVMClassifierPatchAnnotatorUIFigure);
            app.ViewAnnotationCheckBox.Visible = 'off';
            app.ViewAnnotationCheckBox.Text = 'View Annotation';
            app.TotalTrainingPatchesLabel = uilabel(app.SVMClassifierPatchAnnotatorUIFigure);
            app.TotalTrainingPatchesLabel.Text = 'Total Training Patches: 0';
            % Show the figure after all components are created
            app.SVMClassifierPatchAnnotatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = classifier

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SVMClassifierPatchAnnotatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SVMClassifierPatchAnnotatorUIFigure)
        end
    end
end