classdef flatmap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        ListBox                        matlab.ui.control.ListBox
        AnnotationsButtonGroup         matlab.ui.container.ButtonGroup
        RegionsButton                  matlab.ui.control.RadioButton
        RegionsButton_2                matlab.ui.control.RadioButton
        UpdateImageButton              matlab.ui.control.Button
        ThresholdSigVoxelsButtonGroup  matlab.ui.container.ButtonGroup
        noneButton                     matlab.ui.control.RadioButton
        pButton                        matlab.ui.control.RadioButton
        padjButton                     matlab.ui.control.RadioButton
        UpperLimitEditFieldLabel       matlab.ui.control.Label
        UpperLimitEditField            matlab.ui.control.NumericEditField
        LowerLiimitEditFieldLabel      matlab.ui.control.Label
        LowerLiimitEditField           matlab.ui.control.NumericEditField
        DisplayBoundariesCheckBox      matlab.ui.control.CheckBox
        MaxLabel                       matlab.ui.control.Label
        MinLabel                       matlab.ui.control.Label
        UpdateTableButton              matlab.ui.control.Button
        EditField                      matlab.ui.control.EditField
        RescaleColormapCheckBox        matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        res_directory = '';
        table_directory = '';
        flat_directory = '';
        fname = '';
        results_table = [];
        fig_handle = [];
        disp_vox = true;
        stat = 1;
        flatview = 'harris';
        rescale_cmap = false;
        bold_sig = true;
        limits = [-100,100]
        max_val = 100;
        min_val = -100;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, results_directory)
            if isstruct(results_directory)
                app.res_directory = fullfile(results_directory.results_directory,...
                    results_directory.prefix);
            elseif isstring(results_directory) || ischar(results_directory)
                app.res_directory = string(results_directory);
            else
                error("Provide config structure or path to results folder.")
            end
            
            app.table_directory = fullfile(app.res_directory,'tables');
            tbl = dir(fullfile(app.table_directory,'*_stats.xls'));
            app.results_table = munge_stats(fullfile(app.table_directory,tbl(1).name));
            app.EditField.Value = tbl(1).name;            
            
            app.flat_directory = fullfile(app.res_directory,'flatmaps');
            files = dir(fullfile(app.flat_directory,'*.nii'));
            app.ListBox.Items = {files.name};
            app.ListBox.Value = app.ListBox.Items{1};
            app.fname = fullfile(app.flat_directory,app.ListBox.Value);
            
            app.AnnotationsButtonGroup.SelectedObject = app.AnnotationsButtonGroup.Children(1);
                   
        end

        % Button pushed function: UpdateImageButton
        function UpdateImageButtonPushed(app, event)
            app.fname = fullfile(app.flat_directory,app.ListBox.Value);
            [~,col_name] = fileparts(app.fname);
            col_name = strsplit(col_name,'_');
            
            a = 1;
            var_names = fieldnames(app.results_table);
            for i = 1:length(var_names)
                names = [app.results_table.(var_names{i}).stats.safe_name];
                if ismember(col_name(2),names)
                    values(:,1) = app.results_table.(var_names{i}).info.index;
                    values(:,2) = app.results_table.(var_names{i}).stats(names == col_name(2)).stats(:,6);
                    break
                end
            end
            
            if isequal(app.ThresholdSigVoxelsButtonGroup.SelectedObject.Text,'none')
                thresh = 'none';
            elseif isequal(app.ThresholdSigVoxelsButtonGroup.SelectedObject.Text,'p')
                thresh = 'p';
            else
                thresh = 'p_adj';
            end
            
            if app.RescaleColormapCheckBox.Value
                rescale = true;
            else
                rescale = false;
            end

            [app.fig_handle,app.max_val,app.min_val] = plot_flat_cortex(app.fname,...
                values,app.stat,app.flatview,app.limits,thresh,rescale);
            app.MaxLabel.Text = strcat('Max: ',num2str(app.max_val));
            app.MinLabel.Text = strcat('Min: ',num2str(app.min_val));           
            
        end

        % Selection changed function: AnnotationsButtonGroup
        function AnnotationsButtonGroupSelectionChanged(app, event)
            selectedButton = app.AnnotationsButtonGroup.SelectedObject;
            if isequal({selectedButton.Text},{'43 Regions'})
                app.flatview = 'harris';
            else
                app.flatview = 'seventeen';
            end
            UpdateImageButtonPushed(app)            
            
        end

        % Value changed function: UpperLimitEditField
        function UpperLimitEditFieldValueChanged(app, event)
            app.limits(2) = app.UpperLimitEditField.Value;

        end

        % Value changed function: LowerLiimitEditField
        function LowerLiimitEditFieldValueChanged(app, event)
            app.limits(1) = app.LowerLiimitEditField.Value;
            
        end

        % Button pushed function: UpdateTableButton
        function UpdateTableButtonPushed(app, event)
            table_path = uigetfile({fullfile(app.table_directory,'*.csv'),...
                fullfile(app.table_directory,'*.xls')},...
                'Select a summary table');
            app.results_table = readtable(table_path,'PreserveVariableNames',true);
            app.EditField.Value = table_path;            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.ListBox = uilistbox(app.Panel);
            app.AnnotationsButtonGroup = uibuttongroup(app.Panel);
            app.AnnotationsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AnnotationsButtonGroupSelectionChanged, true);
            app.AnnotationsButtonGroup.Title = 'Annotations';
            app.RegionsButton = uiradiobutton(app.AnnotationsButtonGroup);
            app.RegionsButton.Text = '17 Regions';
            app.RegionsButton.Value = true;
            app.RegionsButton_2 = uiradiobutton(app.AnnotationsButtonGroup);
            app.RegionsButton_2.Text = '43 Regions';
            app.UpdateImageButton = uibutton(app.Panel, 'push');
            app.UpdateImageButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateImageButtonPushed, true);
            app.UpdateImageButton.Text = 'Update Image';
            app.ThresholdSigVoxelsButtonGroup = uibuttongroup(app.Panel);
            app.ThresholdSigVoxelsButtonGroup.Title = 'Threshold Sig. Voxels';
            app.noneButton = uiradiobutton(app.ThresholdSigVoxelsButtonGroup);
            app.noneButton.Text = 'none';
            app.noneButton.Value = true;
            app.pButton = uiradiobutton(app.ThresholdSigVoxelsButtonGroup);
            app.pButton.Text = 'p';
            app.padjButton = uiradiobutton(app.ThresholdSigVoxelsButtonGroup);
            app.padjButton.Text = 'p.adj';
            app.UpperLimitEditFieldLabel = uilabel(app.Panel);
            app.UpperLimitEditFieldLabel.Text = 'Upper Limit';
            app.UpperLimitEditField = uieditfield(app.Panel, 'numeric');
            app.UpperLimitEditField.Limits = [-100 Inf];
            app.UpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @UpperLimitEditFieldValueChanged, true);
            app.UpperLimitEditField.Value = 100;
            app.LowerLiimitEditFieldLabel = uilabel(app.Panel);
            app.LowerLiimitEditFieldLabel.Text = 'Lower Liimit';
            app.LowerLiimitEditField = uieditfield(app.Panel, 'numeric');
            app.LowerLiimitEditField.Limits = [-100 Inf];
            app.LowerLiimitEditField.RoundFractionalValues = 'on';
            app.LowerLiimitEditField.ValueChangedFcn = createCallbackFcn(app, @LowerLiimitEditFieldValueChanged, true);
            app.LowerLiimitEditField.Value = -100;
            app.DisplayBoundariesCheckBox = uicheckbox(app.Panel);
            app.DisplayBoundariesCheckBox.Text = 'Display Boundaries';
            app.DisplayBoundariesCheckBox.Value = true;
            app.MaxLabel = uilabel(app.Panel);
            app.MaxLabel.Text = 'Max: ';
            app.MinLabel = uilabel(app.Panel);
            app.MinLabel.Text = 'Min:';
            app.UpdateTableButton = uibutton(app.Panel, 'push');
            app.UpdateTableButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTableButtonPushed, true);
            app.UpdateTableButton.Text = 'Update Table';
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.Editable = 'off';
            app.RescaleColormapCheckBox = uicheckbox(app.Panel);
            app.RescaleColormapCheckBox.Text = 'Rescale Colormap';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = flatmap(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end