classdef slices < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Panel                      matlab.ui.container.Panel
        UpdatePlotButton           matlab.ui.control.Button
        ThreshSigVoxButtonGroup    matlab.ui.container.ButtonGroup
        noneButton                 matlab.ui.control.RadioButton
        pButton                    matlab.ui.control.RadioButton
        padjButton                 matlab.ui.control.RadioButton
        UpperLimitEditFieldLabel   matlab.ui.control.Label
        UpperLimitEditField        matlab.ui.control.NumericEditField
        LowerLimitEditFieldLabel   matlab.ui.control.Label
        LowerLimitEditField        matlab.ui.control.NumericEditField
        DisplayBoundariesCheckBox  matlab.ui.control.CheckBox
        MaxLabel                   matlab.ui.control.Label
        MinLabel                   matlab.ui.control.Label
        UpdateTableButton          matlab.ui.control.Button
        EditField                  matlab.ui.control.EditField
        RescaleLimitsCheckBox      matlab.ui.control.CheckBox
        OrientationButtonGroup     matlab.ui.container.ButtonGroup
        CoronalButton              matlab.ui.control.RadioButton
        SaggittalButton            matlab.ui.control.RadioButton
        AxialButton                matlab.ui.control.RadioButton
        sliceSpinnerLabel          matlab.ui.control.Label
        sliceSpinner               matlab.ui.control.Spinner
        statSpinnerLabel           matlab.ui.control.Label
        leftstatSpinner            matlab.ui.control.Spinner
        rightstatSpinner           matlab.ui.control.Spinner
        markerLabel                matlab.ui.control.Label
        leftmarkSpinner            matlab.ui.control.Spinner
        rightmarkSpinner           matlab.ui.control.Spinner
        CategoryGroup              matlab.ui.container.ButtonGroup
        VolumeButton               matlab.ui.control.RadioButton
        CountsButton               matlab.ui.control.RadioButton
        DensityButton              matlab.ui.control.RadioButton
        VoxelButton                matlab.ui.control.RadioButton
        EditField_2                matlab.ui.control.EditField
        markerDropDownLabel        matlab.ui.control.Label
        markerDropDown             matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        res_directory = '';
        table_directory = '';
        plot_directory = '';
        fname = '';
        results_table = [];
        fig_handle = [];
        vox = [];
        
        key_l = [68,0,2,1];
        key_r = [68,1,2,1];
        
        category = 2;
        orientation = 'coronal';
        thresh_sig = false;
        display_boundaries = true;

        limits = [-100,100]
        max_val = 100;
        min_val = -100;
    end
    
    methods (Access = private)
        
        function results = update_keys(app)
            z = app.sliceSpinner.Value;
            
            m_l = app.leftmarkSpinner.Value;
            m_r = app.rightmarkSpinner.Value;
            
            category = app.CategoryGroup.SelectedObject.Text;
            switch category
                case 'Volume'
                    category = 1;
                case 'Counts'
                    category = 2;
                case 'Density'
                    category = 3;
                case 'Voxel'
                    category = 4;
                otherwise
                    error("Unrecognized category.")
            end
            
            s_l = app.leftstatSpinner.Value;
            s_r = app.rightstatSpinner.Value;
            
            app.key_l = [z,m_l,category,s_l];
            app.key_r = [z,m_r,category,s_r];    
            app.category = category;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, config)
            if isstruct(config)
                app.res_directory = fullfile(config.results_directory,config.prefix);
            else
                error("Provide config structure.")
            end
            
            % Get table
            app.table_directory = fullfile(app.res_directory,'tables');
            tbl = dir(fullfile(app.table_directory,'*_stats.xls'));
            app.results_table = munge_stats(fullfile(app.table_directory,tbl(1).name));
            app.EditField.Value = tbl(1).name;            
            
            % Create plot data
            app.plot_directory = dir(fullfile(app.res_directory,"*_plotData.mat"));
            if isempty(app.plot_directory)
                NM_evaluate(config,'plot','coronal')
                app.plot_directory = dir(fullfile(app.res_directory,"*_plotData.mat"));
            end
            app.plot_directory = fullfile(app.plot_directory(1).folder, app.plot_directory(1).name);
            [~,fname] = fileparts(app.plot_directory);            
            app.EditField_2.Value = fname;
            
            %app.AnnotationsButtonGroup.SelectedObject = app.AnnotationsButtonGroup.Children(1);
                   
        end

        % Button pushed function: UpdatePlotButton
        function UpdatePlotButtonPushed(app, event)
            
            app.update_keys;
            
            if isempty(app.vox)
                app.vox = load(app.plot_directory,'coronal');
                app.vox = app.vox.('coronal');
            end
            
            % Update marker limits
            app.leftmarkSpinner.Limits = [0,length(app.vox.markers)];
            app.rightmarkSpinner.Limits = [0,length(app.vox.markers)];

            % If voxel, restrict to fold change and p values
            if app.category == 4
               if app.key_l(end) < 5
                   app.key_l(end) = 5;
                   app.leftstatSpinner.Value = 5;
               elseif app.key_l(end) > 7
                   app.key_l(end) = 7;
                   app.leftstatSpinner.Value = 7;
               end
              if app.key_r(end) < 5
                   app.key_r(end) = 5;
                   app.rightstatSpinner.Value = 5;
               elseif app.key_r(end) > 7
                   app.key_r(end) = 7;
                   app.rightstatSpinner.Value = 7;
              end
            end
            
            close(gcf)
            key{1} = app.key_l;
            key{2} = app.key_r;
            [p1, p2] = display_slice_gui(app.vox,key);
            figure(app.UIFigure)
            
            app.MaxLabel.Text = strcat('Max: ',num2str(app.max_val));
            app.MinLabel.Text = strcat('Min: ',num2str(app.min_val));           
            
        end

        % Callback function
        function AnnotationsButtonGroupSelectionChanged(app, event)
            selectedButton = app.AnnotationsButtonGroup.SelectedObject;
            if isequal({selectedButton.Text},{'43 Regions'})
                app.flatview = 'harris';
            else
                app.flatview = 'seventeen';
            end
            UpdatePlotButtonPushed(app)
            %[app.fig_handle,app.max_val,app.min_val] = plot_flat_cortex(app.fname,app.stat,app.flatview,app.limits);


        end

        % Value changed function: UpperLimitEditField
        function UpperLimitEditFieldValueChanged(app, event)
            app.limits(2) = app.UpperLimitEditField.Value;

        end

        % Value changed function: LowerLimitEditField
        function LowerLimitEditFieldValueChanged(app, event)
            app.limits(1) = app.LowerLimitEditField.Value;
            
        end

        % Button pushed function: UpdateTableButton
        function UpdateTableButtonPushed(app, event)
            table_path = uigetfile({fullfile(app.table_directory,'*.csv'),...
                fullfile(app.table_directory,'*.xls')},...
                'Select a summary table');
            app.results_table = readtable(table_path,'PreserveVariableNames',true);
            app.EditField.Value = table_path;            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.UpdatePlotButton = uibutton(app.Panel, 'push');
            app.UpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotButtonPushed, true);
            app.UpdatePlotButton.Text = 'Update Plot';
            app.ThreshSigVoxButtonGroup = uibuttongroup(app.Panel);
            app.ThreshSigVoxButtonGroup.Title = 'Thresh. Sig. Vox.';
            app.noneButton = uiradiobutton(app.ThreshSigVoxButtonGroup);
            app.noneButton.Text = 'none';
            app.noneButton.Value = true;
            app.pButton = uiradiobutton(app.ThreshSigVoxButtonGroup);
            app.pButton.Text = 'p';
            app.padjButton = uiradiobutton(app.ThreshSigVoxButtonGroup);
            app.padjButton.Text = 'p.adj';
            app.UpperLimitEditFieldLabel = uilabel(app.Panel);
            app.UpperLimitEditFieldLabel.Text = 'Upper Limit';
            app.UpperLimitEditField = uieditfield(app.Panel, 'numeric');
            app.UpperLimitEditField.Limits = [-100 Inf];
            app.UpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @UpperLimitEditFieldValueChanged, true);
            app.UpperLimitEditField.Value = 100;
            app.LowerLimitEditFieldLabel = uilabel(app.Panel);
            app.LowerLimitEditFieldLabel.Text = 'Lower Limit';
            app.LowerLimitEditField = uieditfield(app.Panel, 'numeric');
            app.LowerLimitEditField.Limits = [-100 Inf];
            app.LowerLimitEditField.RoundFractionalValues = 'on';
            app.LowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @LowerLimitEditFieldValueChanged, true);
            app.LowerLimitEditField.Value = -100;
            app.DisplayBoundariesCheckBox = uicheckbox(app.Panel);
            app.DisplayBoundariesCheckBox.Text = 'Display Boundaries';
            app.DisplayBoundariesCheckBox.Value = true;
            app.MaxLabel = uilabel(app.Panel);
            app.MaxLabel.Text = 'Max: ';
            app.MinLabel = uilabel(app.Panel);
            app.MinLabel.Text = 'Min:';
            app.UpdateTableButton = uibutton(app.Panel, 'push');
            app.UpdateTableButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTableButtonPushed, true);
            app.UpdateTableButton.Text = 'Update Table';
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.Editable = 'off';
            app.RescaleLimitsCheckBox = uicheckbox(app.Panel);
            app.RescaleLimitsCheckBox.Visible = 'off';
            app.RescaleLimitsCheckBox.Text = 'Rescale Limits';
            app.OrientationButtonGroup = uibuttongroup(app.Panel);
            app.OrientationButtonGroup.Title = 'Orientation';
            app.CoronalButton = uiradiobutton(app.OrientationButtonGroup);
            app.CoronalButton.Text = 'Coronal';
            app.CoronalButton.Value = true;
            app.SaggittalButton = uiradiobutton(app.OrientationButtonGroup);
            app.SaggittalButton.Text = 'Saggittal';
            app.AxialButton = uiradiobutton(app.OrientationButtonGroup);
            app.AxialButton.Text = 'Axial';
            app.sliceSpinnerLabel = uilabel(app.Panel);
            app.sliceSpinnerLabel.Text = 'slice';
            app.sliceSpinner = uispinner(app.Panel);
            app.sliceSpinner.Limits = [1 137];
            app.sliceSpinner.RoundFractionalValues = 'on';
            app.sliceSpinner.Value = 68;
            app.statSpinnerLabel = uilabel(app.Panel);
            app.statSpinnerLabel.Text = 'stat';
            app.leftstatSpinner = uispinner(app.Panel);
            app.leftstatSpinner.Limits = [1 8];
            app.leftstatSpinner.RoundFractionalValues = 'on';
            app.leftstatSpinner.Value = 5;
            app.rightstatSpinner = uispinner(app.Panel);
            app.rightstatSpinner.Limits = [1 8];
            app.rightstatSpinner.RoundFractionalValues = 'on';
            app.rightstatSpinner.Value = 7;
            app.markerLabel = uilabel(app.Panel);
            app.markerLabel.Text = 'marker';
            app.leftmarkSpinner = uispinner(app.Panel);
            app.leftmarkSpinner.Limits = [0 1];
            app.leftmarkSpinner.RoundFractionalValues = 'on';
            app.rightmarkSpinner = uispinner(app.Panel);
            app.rightmarkSpinner.Limits = [0 1];
            app.rightmarkSpinner.RoundFractionalValues = 'on';
            app.rightmarkSpinner.Value = 1;
            app.CategoryGroup = uibuttongroup(app.Panel);
            app.VolumeButton = uiradiobutton(app.CategoryGroup);
            app.VolumeButton.Text = 'Volume';
            app.CountsButton = uiradiobutton(app.CategoryGroup);
            app.CountsButton.Text = 'Counts';
            app.DensityButton = uiradiobutton(app.CategoryGroup);
            app.DensityButton.Text = 'Density';
            app.VoxelButton = uiradiobutton(app.CategoryGroup);
            app.VoxelButton.Text = 'Voxel';
            app.VoxelButton.Value = true;
            app.EditField_2 = uieditfield(app.Panel, 'text');
            app.EditField_2.Editable = 'off';
            app.markerDropDownLabel = uilabel(app.Panel);
            app.markerDropDownLabel.Text = 'marker';
            app.markerDropDown = uidropdown(app.Panel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = slices(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end